classdef STSGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TimeSlider             matlab.ui.control.Slider
        LabelNumericEditField  matlab.ui.control.Label
        TimeField              matlab.ui.control.NumericEditField
        PlayButton             matlab.ui.control.StateButton
        Decrement              matlab.ui.control.Button
        Increment              matlab.ui.control.Button
        SlowPlay               matlab.ui.control.StateButton
        SubjectDropDownLabel   matlab.ui.control.Label
        SubjectDropDown        matlab.ui.control.DropDown
        StrategyDropDownLabel  matlab.ui.control.Label
        StrategyDropDown       matlab.ui.control.DropDown
        TrialDropDownLabel     matlab.ui.control.Label
        TrialDropDown          matlab.ui.control.DropDown
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        SBTypeDropDownLabel    matlab.ui.control.Label
        SBTypeDropDown         matlab.ui.control.DropDown
        UIAxes3                matlab.ui.control.UIAxes
        PrevButton             matlab.ui.control.Button
        NextButton             matlab.ui.control.Button
        HorizontalLabel        matlab.ui.control.Label
        VerticalLabel          matlab.ui.control.Label
        ObservationLabel       matlab.ui.control.Label
        PredictionLabel        matlab.ui.control.Label
        ObsLabel               matlab.ui.control.Label
        PredLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        success_color = 1/256*[158,202,225];
        fail_color = 1/256*[253,174,107];
        
        anim = 0;
        TimeStepListener = 0;
        Playlistener = 0;
        Closelistener = 0;
        TrialListListener = 0;
        trialListener = 0;
        info
    end
    
    methods
        function set.anim(app, anim)
            
            app.TimeStepListener = addlistener(anim, 'newTimeStep', @(s, e) app.ListenToNewTimeStep(s, e));
            app.Playlistener = addlistener(anim, 'playStateChanged', @(s, e) app.ListenToPlayStateChanged(s, e));
            app.Closelistener = addlistener(anim, 'animDeleting', @(s, e) app.ListenToClosing(s, e));
            app.TrialListListener = addlistener(anim, 'trialListChanged', @(s, e) app.ListenToTrialList(s, e));
            app.trialListener = addlistener(anim, 'trialChanged', @(s, e) app.ListenToNewTrial(s, e));
            
            app.anim = anim;
            app.TimeSlider.Limits = [anim.startTime, anim.endTime];
            app.TimeSlider.MajorTicks = [anim.startTime, anim.endTime];
            
            app.PlayButton.Value = anim.isPlaying;
            
        end
        
        function ListenToNewTimeStep(app, src, eventData)
            app.TimeSlider.Value = eventData.t;
            app.TimeField.Value = eventData.t;
        end
        
        function ListenToPlayStateChanged(app, src, eventData)
            app.PlayButton.Value = app.anim.isPlaying;
            app.SlowPlay.Value = app.anim.isPlaying;
        end
        
        function ListenToClosing(app, src, eventData)
            disp('Control bar deleted.');
            delete(app);
        end
        
        function ListenToTrialList(app, src, eventData)
            app.TrialDropDown.Items = app.anim.trial_list;
            app.info.trial = app.anim.info.trial;
            app.TrialDropDown.Value = app.info.trial;
        end
        
        function ListenToNewTrial(app, src, eventData)
            app.TimeSlider.Limits = [app.anim.startTime, app.anim.endTime];
            app.TimeSlider.MajorTicks = [app.anim.startTime, app.anim.endTime];
            
            app.ObsLabel.Text = app.anim.mytraj.classification;
            if strcmp(app.anim.mytraj.classification, 'success')
                app.ObsLabel.FontColor = app.success_color;
            else
                app.ObsLabel.FontColor = app.fail_color;
            end
            
            app.PredLabel.Text = app.anim.mypred;
            if strcmp(app.anim.mypred, 'success')
                app.PredLabel.FontColor = app.success_color;
            else
                app.PredLabel.FontColor = app.fail_color;
            end
        end
    end
    
    methods (Access = public, Static)
        function pov = TextToPOV(text)
            switch(text)
                case 'North'
                    pov = Animator.AnimatorPointOfView.North;
                case 'South'
                    pov = Animator.AnimatorPointOfView.South;
                case 'East'
                    pov = Animator.AnimatorPointOfView.East;
                case 'West'
                    pov = Animator.AnimatorPointOfView.West;
                case 'Front'
                    pov = Animator.AnimatorPointOfView.Front;
                case 'Back'
                    pov = Animator.AnimatorPointOfView.Back;
                case 'Left'
                    pov = Animator.AnimatorPointOfView.Left;
                case 'Right'
                    pov = Animator.AnimatorPointOfView.Right;
                case 'Top Front Left'
                    pov = Animator.AnimatorPointOfView.TopFrontLeft;
                case 'Top South East'
                    pov = Animator.AnimatorPointOfView.TopSouthEast;
                otherwise
                    pov = Animator.AnimatorPointOfView.Free;
            end
        end
        
        function text = POVToText(pov)
            switch(pov)
                case Animator.AnimatorPointOfView.North
                    text = 'North';
                case Animator.AnimatorPointOfView.South
                    text = 'South';
                case Animator.AnimatorPointOfView.East
                    text = 'East';
                case Animator.AnimatorPointOfView.West
                    text = 'West';
                case Animator.AnimatorPointOfView.Front
                    text = 'Front';
                case Animator.AnimatorPointOfView.Back
                    text = 'Back';
                case Animator.AnimatorPointOfView.Left
                    text = 'Left';
                case Animator.AnimatorPointOfView.Right
                    text = 'Right';
                case Animator.AnimatorPointOfView.TopFrontLeft
                    text = 'Top Front Left';
                case Animator.AnimatorPointOfView.TopSouthEast
                    text = 'Top South East';
                case Animator.AnimatorPointOfView.Free
                    text = 'Free';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.info = struct();
            app.info.subject = app.SubjectDropDown.Value;
            app.info.strategy = app.StrategyDropDown.Value;
            app.info.trial = app.TrialDropDown.Value;
            app.info.SBtype = app.SBTypeDropDown.Value;
        end

        % Value changed function: TimeSlider
        function TimeSliderChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.TimeSlider.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Value changed function: TimeField
        function TimeFieldChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.TimeField.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Value changed function: PlayButton
        function PlayChanged(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = app.PlayButton.Value;
            app.anim.speed = app.anim.orig_speed;
            
            if app.anim.currentTime >= app.anim.endTime
                app.anim.currentTime = app.anim.startTime;
            end
            
            app.anim.isPlaying = value;
        end

        % Value changing function: TimeSlider
        function TimeSliderChanging(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            value = event.Value;
            
            app.anim.currentTime = value;
            app.anim.Animate(true);
        end

        % Callback function
        function SkipForward2(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            if app.anim.isPlaying
                app.anim.speed = max(-app.anim.speed, 1.2*app.anim.speed);
            else
                app.anim.currentTime = app.anim.currentTime + 1;
            end
            app.anim.Animate(true);
        end

        % Callback function
        function SkipBackward(app, event)
            if isnumeric(app.anim)
                return;
            end
            
            if app.anim.isPlaying
                app.anim.speed = min(-app.anim.speed, 1.2*app.anim.speed);
            else
                app.anim.currentTime = app.anim.currentTime - 1;
            end
            app.anim.Animate(true);
        end

        % Button pushed function: Decrement
        function DecrementButtonPushed(app, event)
            if isnumeric(app.anim)
                return;
            end
            app.anim.currentTime = app.anim.currentTime - 0.05;
            app.anim.Animate(true);
        end

        % Button pushed function: Increment
        function IncrementButtonPushed(app, event)
            if isnumeric(app.anim)
                return;
            end
            app.anim.currentTime = app.anim.currentTime + 0.05;
            app.anim.Animate(true);
        end

        % Value changed function: SlowPlay
        function SlowPlayValueChanged(app, event)
            value = app.SlowPlay.Value;
            if isnumeric(app.anim)
                return;
            end
            
            value = app.SlowPlay.Value;
            app.anim.speed = 0.2*app.anim.orig_speed;
            
            if app.anim.currentTime >= app.anim.endTime
                app.anim.currentTime = app.anim.startTime;
            end
            
            app.anim.isPlaying = value;
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            value = app.SubjectDropDown.Value;
            app.anim.isPlaying = false;
            app.PlayButton.Value = false;
            app.SlowPlay.Value = false;
            app.info.subject = value;
            app.anim = app.anim.updateInfo(app.info);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            value = app.StrategyDropDown.Value;
            app.anim.isPlaying = false;
            app.PlayButton.Value = false;
            app.SlowPlay.Value = false;
            app.info.strategy = value;
            app.anim = app.anim.updateInfo(app.info);
        end

        % Value changed function: TrialDropDown
        function TrialDropDownValueChanged(app, event)
            value = app.TrialDropDown.Value;
            app.anim.isPlaying = false;
            app.PlayButton.Value = false;
            app.SlowPlay.Value = false;
            app.info.trial = value;
            app.anim = app.anim.updateInfo(app.info);
        end

        % Value changed function: SBTypeDropDown
        function SBTypeDropDownValueChanged(app, event)
            value = app.SBTypeDropDown.Value;
            app.anim.isPlaying = false;
            app.PlayButton.Value = false;
            app.SlowPlay.Value = false;
            app.info.SBtype = value;
            app.anim = app.anim.updateInfo(app.info);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.anim.isPlaying = false;
            app.PlayButton.Value = false;
            app.SlowPlay.Value = false;
            curr_idx = find(strcmp(app.info.trial, app.TrialDropDown.Items));
            next_idx = curr_idx + 1;
            next_idx = min(next_idx, length(app.TrialDropDown.Items));
            app.info.trial = app.TrialDropDown.Items{next_idx};
            app.TrialDropDown.Value = app.info.trial;
            app.anim = app.anim.updateInfo(app.info);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.anim.isPlaying = false;
            app.PlayButton.Value = false;
            app.SlowPlay.Value = false;
            curr_idx = find(strcmp(app.info.trial, app.TrialDropDown.Items));
            prev_idx = curr_idx - 1;
            prev_idx = max(prev_idx, 1);
            app.info.trial = app.TrialDropDown.Items{prev_idx};
            app.TrialDropDown.Value = app.info.trial;
            app.anim = app.anim.updateInfo(app.info);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.MajorTicks = [0 100];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderChanging, true);
            app.TimeSlider.MinorTicks = [];
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Time';
            app.TimeField = uieditfield(app.UIFigure, 'numeric');
            app.TimeField.ValueDisplayFormat = '%04.03f';
            app.TimeField.ValueChangedFcn = createCallbackFcn(app, @TimeFieldChanged, true);
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayChanged, true);
            app.PlayButton.Text = 'Play';
            app.Decrement = uibutton(app.UIFigure, 'push');
            app.Decrement.ButtonPushedFcn = createCallbackFcn(app, @DecrementButtonPushed, true);
            app.Decrement.Text = '<';
            app.Increment = uibutton(app.UIFigure, 'push');
            app.Increment.ButtonPushedFcn = createCallbackFcn(app, @IncrementButtonPushed, true);
            app.Increment.Text = '>';
            app.SlowPlay = uibutton(app.UIFigure, 'state');
            app.SlowPlay.ValueChangedFcn = createCallbackFcn(app, @SlowPlayValueChanged, true);
            app.SlowPlay.Text = 'Slow Play';
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SubjectDropDown.Value = '1';
            app.StrategyDropDownLabel = uilabel(app.UIFigure);
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.UIFigure);
            app.StrategyDropDown.Items = {'MT', 'N', 'QS'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Value = 'N';
            app.TrialDropDownLabel = uilabel(app.UIFigure);
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.UIFigure);
            app.TrialDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialDropDownValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SBTypeDropDownLabel = uilabel(app.UIFigure);
            app.SBTypeDropDownLabel.Text = 'SB Type';
            app.SBTypeDropDown = uidropdown(app.UIFigure);
            app.SBTypeDropDown.Items = {'InputBounds', 'LQR', 'FFFB', 'naive'};
            app.SBTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SBTypeDropDownValueChanged, true);
            app.SBTypeDropDown.Value = 'InputBounds';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.HorizontalLabel = uilabel(app.UIFigure);
            app.HorizontalLabel.Text = 'Horizontal';
            app.VerticalLabel = uilabel(app.UIFigure);
            app.VerticalLabel.Text = 'Vertical';
            app.ObservationLabel = uilabel(app.UIFigure);
            app.ObservationLabel.Text = 'Observation:';
            app.PredictionLabel = uilabel(app.UIFigure);
            app.PredictionLabel.Text = 'Prediction:';
            app.ObsLabel = uilabel(app.UIFigure);
            app.ObsLabel.Text = 'success';
            app.PredLabel = uilabel(app.UIFigure);
            app.PredLabel.Text = 'success';
        end
    end

    methods (Access = public)

        % Construct app
        function app = STSGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end